classdef ProiectLicenta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        proiectLicenta          matlab.ui.Figure
        exporting_Panel         matlab.ui.container.Panel
        filename_EditField      matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
        filetype_DropDown       matlab.ui.control.DropDown
        FileTypeDropDownLabel   matlab.ui.control.Label
        quality_Slider          matlab.ui.control.Slider
        Label                   matlab.ui.control.Label
        cancel_Button           matlab.ui.control.Button
        save_Button             matlab.ui.control.Button
        settings_Panel          matlab.ui.container.Panel
        layers_Panel            matlab.ui.container.Panel
        tools_Panel             matlab.ui.container.Panel
        actions_Panel           matlab.ui.container.Panel
        export_Button           matlab.ui.control.Button
        import_Button           matlab.ui.control.Button
        canvas_UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CanvasImage = Image("") % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function autoScalingOFF(app)
            app.canvas_UIAxes.Toolbar = [];
            disableDefaultInteractivity(app.canvas_UIAxes);
        end

        % Button pushed function: import_Button
        function import_ButtonPushed(app, event)
            try
                [file, path] = uigetfile('*.jpg;*.png;*.bmp');

                tempFile = strsplit(file,'.');
                app.filename_EditField.Value = tempFile{1};
                
                type = strsplit(file,'.');
                if length(type) > 1
                    extension = upper(type{end}); 
                    app.filetype_DropDown.Value = extension;
                end

                if isequal(file, 0)
                    disp("Canceled!");
                else
                    fullPath = fullfile(path,file);
                    app.CanvasImage = Image(fullPath);
                    app.CanvasImage.showImage(app.canvas_UIAxes);
                end
            catch Er
                disp('Error: ' + Er.message);
            end
        end

        % Button pushed function: export_Button
        function export_ButtonPushed(app, event)
            if  isequal(app.CanvasImage.image, [])
                disp("Error: Canvas is empty");
            else
                app.exporting_Panel.Visible = 'on';
            end
        end

        % Button pushed function: cancel_Button
        function cancel_ButtonPushed(app, event)
            app.exporting_Panel.Visible = "off";
        end

        % Button pushed function: save_Button
        function save_ButtonPushed(app, event)
            k = round(app.quality_Slider.Value); 
            app.CanvasImage = app.CanvasImage.Compression(k);

            [path, file, ext] = fileparts(app.CanvasImage.path); 

            newFileName = strcat(app.filename_EditField.Value, '.', lower(app.filetype_DropDown.Value));
            newFullPath = fullfile(path, newFileName); 

            imwrite(app.CanvasImage.image, newFullPath);

            app.exporting_Panel.Visible = "off";
            app.CanvasImage = Image("");
            cla(app.canvas_UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.proiectLicenta = uifigure('Visible', 'off');
            app.proiectLicenta.Name = 'Spectra';
            app.canvas_UIAxes = uiaxes(app.proiectLicenta);
            title(app.canvas_UIAxes, 'Canvas')
            xlabel(app.canvas_UIAxes, 'X')
            ylabel(app.canvas_UIAxes, 'Y')
            zlabel(app.canvas_UIAxes, 'Z')
            app.actions_Panel = uipanel(app.proiectLicenta);
            app.import_Button = uibutton(app.actions_Panel, 'push');
            app.import_Button.ButtonPushedFcn = createCallbackFcn(app, @import_ButtonPushed, true);
            app.import_Button.Text = 'Import';
            app.export_Button = uibutton(app.actions_Panel, 'push');
            app.export_Button.ButtonPushedFcn = createCallbackFcn(app, @export_ButtonPushed, true);
            app.export_Button.Text = 'Export';
            app.tools_Panel = uipanel(app.proiectLicenta);
            app.tools_Panel.Title = 'Tools';
            app.layers_Panel = uipanel(app.proiectLicenta);
            app.layers_Panel.Title = 'Layers';
            app.settings_Panel = uipanel(app.proiectLicenta);
            app.settings_Panel.Title = 'Settings';
            app.exporting_Panel = uipanel(app.proiectLicenta);
            app.exporting_Panel.Title = 'Export';
            app.exporting_Panel.Visible = 'off';
            app.save_Button = uibutton(app.exporting_Panel, 'push');
            app.save_Button.ButtonPushedFcn = createCallbackFcn(app, @save_ButtonPushed, true);
            app.save_Button.Text = 'Save';
            app.cancel_Button = uibutton(app.exporting_Panel, 'push');
            app.cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @cancel_ButtonPushed, true);
            app.cancel_Button.Text = 'Cancel';
            app.Label = uilabel(app.exporting_Panel);
            app.Label.Text = 'Quality';
            app.quality_Slider = uislider(app.exporting_Panel);
            app.quality_Slider.Value = 100;
            app.FileTypeDropDownLabel = uilabel(app.exporting_Panel);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.filetype_DropDown = uidropdown(app.exporting_Panel);
            app.filetype_DropDown.Items = {'JPG', 'PNG', 'BMP'};
            app.filetype_DropDown.Value = 'JPG';
            app.FileNameEditFieldLabel = uilabel(app.exporting_Panel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.filename_EditField = uieditfield(app.exporting_Panel, 'text');
            % Show the figure after all components are created
            app.proiectLicenta.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProiectLicenta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.proiectLicenta)

            % Execute the startup function
            runStartupFcn(app, @autoScalingOFF)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.proiectLicenta)
        end
    end
end